classdef ONHSegmentationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ONHSegmentationAppUIFigure     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SegmentacjaManualnaTab         matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        WgrajzdjciadoprzetwarzaniaButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        PodgldLabel                    matlab.ui.control.Label
        ZdjciadoanalizyListBox         matlab.ui.control.ListBox
        PathHandler                    matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
        WstpneprzetwarzanieButton      matlab.ui.control.Button
        ObrazpoprzetwarzaniuwstpnymLabel  matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        KonturmaskiLabel               matlab.ui.control.Label
        WysegmentowanyobszarLabel      matlab.ui.control.Label
        ZlokalizowanegranicetarczynerwuwzrokowegoLabel  matlab.ui.control.Label
        SegmentujobrazButton           matlab.ui.control.Button
        RedukujnaczyniaCheckBox        matlab.ui.control.CheckBox
        INFORMACJELabel                matlab.ui.control.Label
        ZAPISZGRANICEODButton          matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        PrgSpinner                     matlab.ui.control.Spinner
        PrgButton                      matlab.ui.control.Button
        ZAPISZMASKButton               matlab.ui.control.Button
        SegmentacjaAutomatycznaTab     matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes_7                       matlab.ui.control.UIAxes
        ZlokalizowaneODListBox_2       matlab.ui.control.ListBox
        PorwnaniemasekTab              matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes_6                       matlab.ui.control.UIAxes
        WspczynnikidopasowaniamasekLabel  matlab.ui.control.Label
        MaskiaGTEditFieldLabel         matlab.ui.control.Label
        MaskiaGTEditField              matlab.ui.control.EditField
        WysegmentowanemaskiaEditFieldLabel  matlab.ui.control.Label
        WysegmentowanemaskiaEditField  matlab.ui.control.EditField
        PorwnajmaskipojedynczoButton   matlab.ui.control.Button
        PorwnajmaskiseryjnieButton     matlab.ui.control.Button
        SrensenDiceLabel               matlab.ui.control.Label
        DiceEditField                  matlab.ui.control.EditField
        JaccardEditField               matlab.ui.control.EditField
        BFScoreEditField               matlab.ui.control.EditField
        JaccardLabel                   matlab.ui.control.Label
        BFScoreLabel                   matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WgrajzdjciadoprzetwarzaniaButton
        function WgrajzdjciadoprzetwarzaniaButtonPushed(app, event)
            global path;
            path = '/Users/wiktorkalaga/Documents/Zasoby do inżynierki/Projekt Inżynierski/ImageDB/';
            info = dir(path);          
            elemslist = {info(:).name};
            elemslist(ismember(elemslist,{'.','..','.DS_Store'}))=[];
            app.ZdjciadoanalizyListBox.Items = natsort(elemslist);
            uialert(app.ONHSegmentationAppUIFigure, 'Pomyślnie wgrano pliki', 'Sukces', 'Icon',"success");
        end

        % Value changed function: ZdjciadoanalizyListBox
        function ZdjciadoanalizyListBoxValueChanged(app, event)
            global I;
            global path;
            global imagePath;
            imagePath = [path app.ZdjciadoanalizyListBox.Value];
            app.PathHandler.Value = imagePath;
            I = imread(app.PathHandler.Value);
            imshow(I, 'Parent', app.UIAxes);
            blankimage = ones(0, 0, 3);
            blankimage(:,:,3) = 0;
            imshow(blankimage, 'Parent', app.UIAxes_2);
            imshow(blankimage, 'Parent', app.UIAxes_3);
            imshow(blankimage, 'Parent', app.UIAxes_4);
            imshow(blankimage, 'Parent', app.UIAxes_5);
            app.PrgButton.Enable = 'on';
            app.PrgSpinner.Enable = 'off';
            app.WstpneprzetwarzanieButton.Enable = 'off';
            app.PrgSpinner.Value = 0;
            app.RedukujnaczyniaCheckBox.Value = 0;
        end

        % Button pushed function: WstpneprzetwarzanieButton
        function WstpneprzetwarzanieButtonPushed(app, event)
            global I;
            global I_prog;
            I_prog = BINARYZACJA(I, app.PrgSpinner.Value, app.RedukujnaczyniaCheckBox.Value);
            imshow(I_prog, 'Parent', app.UIAxes_2);
            app.SegmentujobrazButton.Enable = 'on';
            app.PrgSpinner.Enable = 'on';
            app.RedukujnaczyniaCheckBox.Enable = 'on';
        end

        % Button pushed function: SegmentujobrazButton
        function SegmentujobrazButtonPushed(app, event)
            global I;
            global I_prog;
            global I_segm;
            global granice_od;
            I_kont = KONTUR(I_prog, 'sobel', 0.5);
            imshow(I_kont, 'Parent', app.UIAxes_3);
            I_segm = SEGMENTACJA_OD(I_kont, 'diamond');
            imshow(I_segm, 'Parent', app.UIAxes_4);
            granice_od = WYZNACZ_GRANICE_OD(I_segm);
            RYSUJ_GRANICE_OD(I, I_segm, granice_od, app.UIAxes_5);
            app.ZAPISZGRANICEODButton.Enable = 'on';
            app.ZAPISZMASKButton.Enable = 'on';
        end

        % Button pushed function: ZAPISZGRANICEODButton
        function ZAPISZGRANICEODButtonPushed(app, event)
            %%%% ZAPISYWANIE ZLOKALIZOWANYCH GRANIC %%%%
            global imagePath;
            [~, name, ~] = fileparts(imagePath);
            startingFolder = name + "_OD.png";
            [~, filepath] = uiputfile(startingFolder, "Zapisywanie");
            exportgraphics(app.UIAxes_5, filepath + startingFolder);
            uialert(app.ONHSegmentationAppUIFigure, 'Pomyślnie zapisano obraz', 'Sukces', 'Icon',"success");
        end

        % Button pushed function: PrgButton
        function PrgButtonPushed(app, event)
            global I;
            app.PrgSpinner.Value = WYZNACZ_PROG(I);
            app.WstpneprzetwarzanieButton.Enable = 'on';
        end

        % Button pushed function: ZAPISZMASKButton
        function ZAPISZMASKButtonPushed(app, event)
            global I_segm;
            global basePath;
            global imagePath;
            [~, name, ~] = fileparts(imagePath);
            basePath = '/Users/wiktorkalaga/Documents/Zasoby do inżynierki/Projekt Inżynierski/';
            savePath = basePath+"ImageMASKS/";
            if ~isfolder(savePath)
                mkdir(basePath, "ImageMASKS");
                imwrite(I_segm, savePath+name+"_MASK.png");
            else
                imwrite(I_segm, savePath+name+"_MASK.png");
            end
            uialert(app.ONHSegmentationAppUIFigure, 'Pomyślnie zapisano obraz', 'Sukces', 'Icon',"success");
        end

        % Button pushed function: PorwnajmaskipojedynczoButton
        function PorwnajmaskipojedynczoButtonPushed(app, event)
            if (exist(app.WysegmentowanemaskiaEditField.Value, 'file') == 2 && ...
                exist(app.MaskiaGTEditField.Value, 'file') == 2)
                try
                segmented_mask = imread(app.WysegmentowanemaskiaEditField.Value);
                gt_mask = imread(app.MaskiaGTEditField.Value);
                [bf_score, dice_coeff, jaccard_coef] = POROWNAJ_MASKI(segmented_mask, gt_mask, "file");
                app.DiceEditField.Value = strcat(num2str(dice_coeff), ' %');
                app.JaccardEditField.Value = strcat(num2str(jaccard_coef), ' %');
                app.BFScoreEditField.Value = strcat(num2str(bf_score), ' %');
                imshowpair(segmented_mask, gt_mask, 'Parent', app.UIAxes_6);
                app.ResetButton.Enable = 'on';
                catch
                    uialert(app.ONHSegmentationAppUIFigure, 'Błąd pliku', 'Błąd', 'Icon', 'error');
                end
            else
                uialert(app.ONHSegmentationAppUIFigure, 'Błędna ścieżka', 'Błąd', 'Icon', 'error');
                blankimage = ones(0, 0, 3);
                blankimage(:,:,3) = 0;
                imshow(blankimage, 'Parent',app.UIAxes_6);
            end
        end

        % Button pushed function: PorwnajmaskiseryjnieButton
        function PorwnajmaskiseryjnieButtonPushed(app, event)
            segmentedFilesPath = app.WysegmentowanemaskiaEditField.Value;
            gtFilesPath = app.MaskiaGTEditField.Value;
            
            if (isfolder(segmentedFilesPath) == 1 && ...
                isfolder(gtFilesPath) == 1)
                POROWNAJ_MASKI(segmentedFilesPath, gtFilesPath, 'folder');
                uialert(app.ONHSegmentationAppUIFigure, ...
                    'Raport z porównania wyeksportowano do pliku', ...
                    'Sukces', 'Icon', 'success');
            else
                uialert(app.ONHSegmentationAppUIFigure, 'Błędna ścieżka', ...
                    'Błąd', 'Icon', 'error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WysegmentowanemaskiaEditField.Value = "";
            app.MaskiaGTEditField.Value = "";
            app.DiceEditField.Value = "";
            app.BFScoreEditField.Value = "";
            app.JaccardEditField.Value = "";
            blankimage = ones(0, 0, 3);
            blankimage(:,:,3) = 0;
            imshow(blankimage, 'Parent',app.UIAxes_6);
            app.ResetButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ONHSegmentationAppUIFigure = uifigure('Visible', 'off');
            app.ONHSegmentationAppUIFigure.Name = 'ONH Segmentation App';
            app.TabGroup = uitabgroup(app.ONHSegmentationAppUIFigure);
            app.TabGroup.Tooltip = {''};
            app.SegmentacjaManualnaTab = uitab(app.TabGroup);
            app.SegmentacjaManualnaTab.Title = 'Segmentacja manualna';
            app.GridLayout = uigridlayout(app.SegmentacjaManualnaTab);
            app.WgrajzdjciadoprzetwarzaniaButton = uibutton(app.GridLayout, 'push');
            app.WgrajzdjciadoprzetwarzaniaButton.ButtonPushedFcn = createCallbackFcn(app, @WgrajzdjciadoprzetwarzaniaButtonPushed, true);
            app.WgrajzdjciadoprzetwarzaniaButton.Text = {'Wgraj zdjęcia do'; 'przetwarzania'};
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [];
            app.PodgldLabel = uilabel(app.GridLayout);
            app.PodgldLabel.Text = 'Podgląd';
            app.ZdjciadoanalizyListBox = uilistbox(app.GridLayout);
            app.ZdjciadoanalizyListBox.Items = {};
            app.ZdjciadoanalizyListBox.ValueChangedFcn = createCallbackFcn(app, @ZdjciadoanalizyListBoxValueChanged, true);
            app.ZdjciadoanalizyListBox.Value = {};
            app.PathHandler = uieditfield(app.GridLayout, 'text');
            app.PathHandler.Editable = 'off';
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.ALim = [0 1];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YAxisLocation = 'origin';
            app.UIAxes_2.YTick = [];
            app.WstpneprzetwarzanieButton = uibutton(app.GridLayout, 'push');
            app.WstpneprzetwarzanieButton.ButtonPushedFcn = createCallbackFcn(app, @WstpneprzetwarzanieButtonPushed, true);
            app.WstpneprzetwarzanieButton.Enable = 'off';
            app.WstpneprzetwarzanieButton.Text = 'Wstępne przetwarzanie';
            app.ObrazpoprzetwarzaniuwstpnymLabel = uilabel(app.GridLayout);
            app.ObrazpoprzetwarzaniuwstpnymLabel.Text = 'Obraz po przetwarzaniu wstępnym';
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.ALim = [0 1];
            app.UIAxes_3.XAxisLocation = 'origin';
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YAxisLocation = 'origin';
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.ALim = [0 1];
            app.UIAxes_4.XAxisLocation = 'origin';
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YAxisLocation = 'origin';
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.ALim = [0 1];
            app.UIAxes_5.XAxisLocation = 'origin';
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YAxisLocation = 'origin';
            app.UIAxes_5.YTick = [];
            app.KonturmaskiLabel = uilabel(app.GridLayout);
            app.KonturmaskiLabel.Text = 'Kontur maski';
            app.WysegmentowanyobszarLabel = uilabel(app.GridLayout);
            app.WysegmentowanyobszarLabel.Text = 'Wysegmentowany obszar';
            app.ZlokalizowanegranicetarczynerwuwzrokowegoLabel = uilabel(app.GridLayout);
            app.ZlokalizowanegranicetarczynerwuwzrokowegoLabel.Text = 'Zlokalizowane granice tarczy nerwu wzrokowego';
            app.SegmentujobrazButton = uibutton(app.GridLayout, 'push');
            app.SegmentujobrazButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentujobrazButtonPushed, true);
            app.SegmentujobrazButton.Enable = 'off';
            app.SegmentujobrazButton.Text = {'Segmentuj'; 'obraz'};
            app.RedukujnaczyniaCheckBox = uicheckbox(app.GridLayout);
            app.RedukujnaczyniaCheckBox.Enable = 'off';
            app.RedukujnaczyniaCheckBox.Text = ' Redukuj naczynia';
            app.INFORMACJELabel = uilabel(app.GridLayout);
            app.INFORMACJELabel.Text = 'INFORMACJE';
            app.ZAPISZGRANICEODButton = uibutton(app.GridLayout, 'push');
            app.ZAPISZGRANICEODButton.ButtonPushedFcn = createCallbackFcn(app, @ZAPISZGRANICEODButtonPushed, true);
            app.ZAPISZGRANICEODButton.Enable = 'off';
            app.ZAPISZGRANICEODButton.Text = 'ZAPISZ GRANICE OD';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.PrgSpinner = uispinner(app.GridLayout);
            app.PrgSpinner.Limits = [0 Inf];
            app.PrgSpinner.RoundFractionalValues = 'on';
            app.PrgSpinner.Enable = 'off';
            app.PrgButton = uibutton(app.GridLayout, 'push');
            app.PrgButton.ButtonPushedFcn = createCallbackFcn(app, @PrgButtonPushed, true);
            app.PrgButton.Enable = 'off';
            app.PrgButton.Text = 'Próg';
            app.ZAPISZMASKButton = uibutton(app.GridLayout, 'push');
            app.ZAPISZMASKButton.ButtonPushedFcn = createCallbackFcn(app, @ZAPISZMASKButtonPushed, true);
            app.ZAPISZMASKButton.Enable = 'off';
            app.ZAPISZMASKButton.Text = 'ZAPISZ MASKĘ';
            app.SegmentacjaAutomatycznaTab = uitab(app.TabGroup);
            app.SegmentacjaAutomatycznaTab.Title = 'Segmentacja automatyczna';
            app.GridLayout2 = uigridlayout(app.SegmentacjaAutomatycznaTab);
            app.UIAxes_7 = uiaxes(app.GridLayout2);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.ZlokalizowaneODListBox_2 = uilistbox(app.GridLayout2);
            app.ZlokalizowaneODListBox_2.Items = {};
            app.ZlokalizowaneODListBox_2.Value = {};
            app.PorwnaniemasekTab = uitab(app.TabGroup);
            app.PorwnaniemasekTab.Title = 'Porównanie masek';
            app.GridLayout3 = uigridlayout(app.PorwnaniemasekTab);
            app.UIAxes_6 = uiaxes(app.GridLayout3);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.ALim = [0 1];
            app.UIAxes_6.XAxisLocation = 'origin';
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YAxisLocation = 'origin';
            app.UIAxes_6.YTick = [];
            app.WspczynnikidopasowaniamasekLabel = uilabel(app.GridLayout3);
            app.WspczynnikidopasowaniamasekLabel.Text = 'Współczynniki dopasowania masek [%]';
            app.MaskiaGTEditFieldLabel = uilabel(app.GridLayout3);
            app.MaskiaGTEditFieldLabel.Text = '📂 Maski/a GT';
            app.MaskiaGTEditField = uieditfield(app.GridLayout3, 'text');
            app.WysegmentowanemaskiaEditFieldLabel = uilabel(app.GridLayout3);
            app.WysegmentowanemaskiaEditFieldLabel.Text = '📂 Wysegmentowane maski/a';
            app.WysegmentowanemaskiaEditField = uieditfield(app.GridLayout3, 'text');
            app.PorwnajmaskipojedynczoButton = uibutton(app.GridLayout3, 'push');
            app.PorwnajmaskipojedynczoButton.ButtonPushedFcn = createCallbackFcn(app, @PorwnajmaskipojedynczoButtonPushed, true);
            app.PorwnajmaskipojedynczoButton.Text = 'Porównaj maski pojedynczo';
            app.PorwnajmaskiseryjnieButton = uibutton(app.GridLayout3, 'push');
            app.PorwnajmaskiseryjnieButton.ButtonPushedFcn = createCallbackFcn(app, @PorwnajmaskiseryjnieButtonPushed, true);
            app.PorwnajmaskiseryjnieButton.Text = 'Porównaj maski seryjnie';
            app.SrensenDiceLabel = uilabel(app.GridLayout3);
            app.SrensenDiceLabel.Text = 'Sørensen-Dice';
            app.DiceEditField = uieditfield(app.GridLayout3, 'text');
            app.DiceEditField.Editable = 'off';
            app.JaccardEditField = uieditfield(app.GridLayout3, 'text');
            app.JaccardEditField.Editable = 'off';
            app.BFScoreEditField = uieditfield(app.GridLayout3, 'text');
            app.BFScoreEditField.Editable = 'off';
            app.JaccardLabel = uilabel(app.GridLayout3);
            app.JaccardLabel.Text = 'Jaccard';
            app.BFScoreLabel = uilabel(app.GridLayout3);
            app.BFScoreLabel.Text = 'BF-Score';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.ONHSegmentationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ONHSegmentationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ONHSegmentationAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ONHSegmentationAppUIFigure)
        end
    end
end